classdef AutoPilot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ServerPortTextArea       matlab.ui.control.TextArea
        ServerPortTextAreaLabel  matlab.ui.control.Label
        ServerIPTextArea         matlab.ui.control.TextArea
        ServerIPTextAreaLabel    matlab.ui.control.Label
        LocalPortTextArea        matlab.ui.control.TextArea
        LocalPortTextAreaLabel   matlab.ui.control.Label
        LocalIPTextArea          matlab.ui.control.TextArea
        LocalIPLabel             matlab.ui.control.Label
        WeighagainButton         matlab.ui.control.Button
        WeightTextArea           matlab.ui.control.TextArea
        WeightTextAreaLabel      matlab.ui.control.Label
        READYButton              matlab.ui.control.Button
        SettingsTextArea         matlab.ui.control.TextArea
        SettingsTextAreaLabel    matlab.ui.control.Label
        ManualButton             matlab.ui.control.Button
        ProtocolTextArea         matlab.ui.control.TextArea
        ProtocolTextAreaLabel    matlab.ui.control.Label
        STARTButton              matlab.ui.control.Button
        SubjectTextArea          matlab.ui.control.TextArea
        SubjectTextAreaLabel     matlab.ui.control.Label
    end

    methods (Access = private)
        % Function to check if all text areas have text
        function checkAllTextAreas(app)
            if ~isempty(app.ProtocolTextArea.Value) && ~isempty(app.SettingsTextArea.Value) && ~isempty(app.SubjectTextArea.Value)
                app.STARTButton.Enable = 'on';
            else
                app.STARTButton.Enable = 'off';
            end
        end

        %         % Value changed function: TextArea1
        %         function SubjectTextAreaValueChanged(app, event)
        %             checkAllTextAreas(app);
        %         end
        %
        %         % Value changed function: TextArea2
        %         function ProtocolTextAreaValueChanged(app, event)
        %             checkAllTextAreas(app);
        %         end
        %
        %         % Value changed function: TextArea3
        %         function SettingsTextAreaValueChanged(app, event)
        %             checkAllTextAreas(app);
                end
            methods (Access = public)

        function protocol = sendupd(~,rfid, weight, remoteIP, remotePort ,localIP,localPort)
            protocol = '';
            % set up the udp connection
            u = udpport("datagram","LocalHost",localIP, "LocalPort", localPort);

            % Prepare the message
            message = sprintf(rfid, weight);

            % Send the message
            write(u, message, remoteIP, remotePort);
            pause(50);

            if u.NumDatagramsAvailable > 0
                data = read(u,15,"uint8");  % Read the received datagram
                pause(30);
                fprintf("received...\n");
                receive_bytes = data.Data;  % Convert to 32-bit binary string
                protocol = char(receive_bytes);

            end


            % Close the UDP connection
            flush(u,"output");
            %
            clear u;
        end
        %         if isempty(app.SettingsTextArea.Value,app.ProtocolTextArea.Value, app.ProtocolTextArea.Value )
        %             app.STARTButton.Enable = 'off';
        %         else
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: READYButton
        function READYButtonPushed(app, event)
            global weightData;
            global meanWeight;

            % Initialize variables
            rfidData = '';
            weightData = [];

            % Setup serial connections
            scalePort = 'COM13';  % Change as necessary
            arduinoPort = 'COM7';  % Change as necessary

            scaleSerial = serialport(scalePort, 9600);
            flush(scaleSerial);
            fopen(scaleSerial);

            arduino = serialport(arduinoPort, 9600);
            flush(arduino);
            fopen(arduino);

            disp('Listening for RFID data...');
            app.SubjectTextArea.Value = 'Waiting for RFID...';

            while true
                try
                    % Wait for RFID data from Arduino
                    bytesAvailable = arduino.NumBytesAvailable;
                    if bytesAvailable > 0
                        data = read(arduino, bytesAvailable, 'char');
                        disp(['Raw Data: ', data]);  % Display raw data for debugging
                        if ~isempty(data)
                            rfid_data = strtrim(data);
                            fprintf('Received RFID ID: %s\n', rfid_data);
                            
                            rfidData = rfid_data(3:14);  % crop whitespaces and arrow
                            app.SubjectTextArea.Value = num2str(rfidData);
                            
                            % move this to the part where Settings and
                            % Protocol are loaded into GUI later
                            app.checkAllTextAreas; 

                            % Call scale function after receiving RFID data
                            app.WeightTextArea.Value = 'Weighing in progress...';
                            scale(scaleSerial);
                        end
                        break
                    end
                catch ME
                    % Handle errors
                    warning(['Error: ', ME.message]);
                end
                pause(0.1);  % Adjust pause duration for responsiveness
            end

            % Close serial ports
            %fclose(scaleSerial);
            %fclose(arduino);


            function scale(scaleSerial)


                flag_skip_lines = 0;
                while flag_skip_lines < 8
                    data = readline(scaleSerial);
                    flag_skip_lines = flag_skip_lines + 1;
                end

                flag_10_reads = 0;
                while flag_10_reads < 10
                    data = readline(scaleSerial);
                    if ~isempty(data)
                        % Extract weight from the data using specified regex pattern
                        weightMatch = regexp(data, '\d+,-?\d+\.\d+,kg,\d+\.\d+,', 'match', 'once');
                        if ~isempty(weightMatch)
                            weightParts = strsplit(weightMatch, ',');
                            weight = str2double(weightParts{2});
                            weightData = [weightData, weight];
                            disp(['Collected weight: ', num2str(weight)]);
                            flag_10_reads = flag_10_reads + 1;
                        else
                            disp('No valid weight data found.');
                        end

                        if length(weightData) == 10
                            % Calculate mean weight
                            meanWeight = mean(weightData);
                            disp(['Calculated mean weight: ', num2str(meanWeight)]);

                            % Update the BpodSystem GUI data with mean weight
                            app.WeightTextArea.Value = num2str(meanWeight);

                            weightData = [];  % Reset weight data
                            flag_10_reads = 0;
                            break;  % Exit the loop after calculating mean for 10 readings
                        end
                    else
                        disp('Empty data received.');
                    end
                end
            end


            % Send the data via UDP
            %                         localIP = app.LocalIPTextArea.Value;
            %                         localPort = app.LocalPortTextArea.Value;
            %                         remoteIP = app.ServerIPTextArea.Value;
            %                         remotePort = app.ServerPortTextArea.Value;
            %                         Weight = app.WeightTextArea.Value;
            %                         protocol = app.sendUDP(rfid_data, Weight, remoteIP, remotePort ,localIP ,localPort);
            %                         app.ProtocolTextArea.Value = protocol;
            %                         fprintf(protocol);
            %                         fprintf("\nDone...");

        end

        % Button pushed function: WeighagainButton
        function WeighagainButtonPushed(app, event)
            weightData = [];

            % Setup serial connections
            scalePort = 'COM13';  % Change as necessary

            scaleSerial = serialport(scalePort, 9600);
            flush(scaleSerial);
            fopen(scaleSerial);

            app.WeightTextArea.Value = 'Weighing in progress...';
            flag_skip_lines = 0;
            while flag_skip_lines < 8
                data = readline(scaleSerial);
                flag_skip_lines = flag_skip_lines + 1;
            end

            flag_10_reads = 0;
            while flag_10_reads < 10
                data = readline(scaleSerial);
                if ~isempty(data)
                    % Extract weight from the data using specified regex pattern
                    weightMatch = regexp(data, '\d+,-?\d+\.\d+,kg,\d+\.\d+,', 'match', 'once');
                    if ~isempty(weightMatch)
                        weightParts = strsplit(weightMatch, ',');
                        weight = str2double(weightParts{2});
                        weightData = [weightData, weight];
                        disp(['Collected weight: ', num2str(weight)]);
                        flag_10_reads = flag_10_reads + 1;
                    else
                        disp('No valid weight data found.');
                    end

                    if length(weightData) == 10
                        % Calculate mean weight
                        meanWeight = mean(weightData);
                        disp(['Calculated mean weight: ', num2str(meanWeight)]);

                        % Update the BpodSystem GUI data with mean weight
                        app.WeightTextArea.Value = num2str(meanWeight);

                        weightData = [];  % Reset weight data
                        flag_10_reads = 0;
                        break;  % Exit the loop after calculating mean for 10 readings
                    end
                else
                    disp('Empty data received.');
                end
            end

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            global BpodSystem % Import the global BpodSystem object

            protocolName = char(BpodSystem.GUIHandles.rfidGUI.ProtocolTextArea.Value);
            subjectName = char(BpodSystem.GUIHandles.rfidGUI.SubjectTextArea.Value);
            settingsName = char(BpodSystem.GUIHandles.rfidGUI.SettingsTextArea.Value);
            settingsFileName = fullfile(BpodSystem.Path.DataFolder, subjectName, protocolName, 'Session Settings', [settingsName '.mat']);
            dataFolder = fullfile(BpodSystem.Path.DataFolder,subjectName,protocolName,'Session Data');
            if ~exist(dataFolder)
                mkdir(dataFolder);
            end

            % On Bpod r2+, if FlexIO channels are configured as analog,
            % setup binary data file
            if BpodSystem.MachineType > 3
                nAnalogChannels = sum(BpodSystem.HW.FlexIO_ChannelTypes == 2);
                if nAnalogChannels > 0
                    analogFilename = [BpodSystem.Path.CurrentDataFile(1:end-4) '_ANLG.dat'];
                    if BpodSystem.Status.RecordAnalog == 1
                        BpodSystem.AnalogDataFile = fopen(analogFilename,'w');
                        if BpodSystem.AnalogDataFile == -1
                            error(['Error: Could not open the analog data file: ' analogFilename])
                        end
                    end
                    BpodSystem.Status.nAnalogSamples = 0;
                end
            end

            BpodSystem.Status.Live = 1;
            BpodSystem.Status.LastEvent = 0;
            BpodSystem.GUIData.ProtocolName = protocolName;
            BpodSystem.GUIData.SubjectName = subjectName;
            BpodSystem.GUIData.SettingsFileName = settingsFileName;
            BpodSystem.Path.Settings = settingsFileName;
            settingStruct = load(BpodSystem.Path.Settings);
            F = fieldnames(settingStruct);
            fieldName = F{1};
            BpodSystem.ProtocolSettings = eval(['settingStruct.' fieldName]);
            BpodSystem.Data = struct;
            if BpodSystem.MachineType > 3
                if nAnalogChannels > 0
                    BpodSystem.Data.Analog = struct;
                    BpodSystem.Data.Analog.info = struct;
                    BpodSystem.Data.Analog.FileName = analogFilename;
                    BpodSystem.Data.Analog.nChannels = nAnalogChannels;
                    BpodSystem.Data.Analog.channelNumbers = find(BpodSystem.HW.FlexIO_ChannelTypes == 2);
                    BpodSystem.Data.Analog.SamplingRate = BpodSystem.HW.FlexIO_SamplingRate;
                    BpodSystem.Data.Analog.nSamples = 0;
                    % Add human-readable info about data fields to 'info struct
                    BpodSystem.Data.Analog.info.FileName = 'Complete path and filename of the binary file to which the raw data was logged';
                    BpodSystem.Data.Analog.info.nChannels = 'The number of Flex I/O channels configured as analog input';
                    BpodSystem.Data.Analog.info.channelNumbers = 'The indexes of Flex I/O channels configured as analog input';
                    BpodSystem.Data.Analog.info.SamplingRate = 'The sampling rate of the analog data. Units = Hz';
                    BpodSystem.Data.Analog.info.nSamples = 'The total number of analog samples captured during the behavior session';
                    BpodSystem.Data.Analog.info.Samples = 'Analog measurements captured. Rows are separate analog input channels. Units = Volts';
                    BpodSystem.Data.Analog.info.Timestamps = 'Time of each sample (computed from sample index and sampling rate)';
                    BpodSystem.Data.Analog.info.TrialNumber = 'Experimental trial during which each analog sample was captured';
                    BpodSystem.Data.Analog.info.TrialData = 'A cell array of Samples. Each cell contains samples captured during a single trial.';
                end
            end
            protocolFolderPath = fullfile(BpodSystem.Path.ProtocolFolder,protocolName);
            protocolPath = fullfile(BpodSystem.Path.ProtocolFolder,protocolName,[protocolName '.m']);
            addpath(protocolFolderPath);
            set(BpodSystem.GUIHandles.RunButton, 'cdata', BpodSystem.GUIData.PauseButton, 'TooltipString', 'Press to pause session');

            % % Send metadata to Bpod Phone Home program (disabled pending a more stable server)
            % isOnline = BpodSystem.check4Internet();
            % if (isOnline == 1) && (BpodSystem.SystemSettings.PhoneHome == 1)
            %     BpodSystem.BpodPhoneHome(1);
            % end

            if BpodSystem.Status.AnalogViewer
                set(BpodSystem.GUIHandles.RecordButton, 'Enable', 'off')
            end

            BpodSystem.Status.BeingUsed = 1;
            BpodSystem.Status.SessionStartFlag = 1;
            BpodSystem.ProtocolStartTime = now*100000;
            BpodSystem.resetSessionClock();
            close(BpodSystem.GUIHandles.rfidGUI.UIFigure);
            disp(' ');
            disp(['Starting ' protocolName]);
            set(BpodSystem.GUIHandles.CurrentStateDisplay, 'String', '---');
            set(BpodSystem.GUIHandles.PreviousStateDisplay, 'String', '---');
            set(BpodSystem.GUIHandles.LastEventDisplay, 'String', '---');
            set(BpodSystem.GUIHandles.TimeDisplay, 'String', '0:00:00');
            if sum(BpodSystem.InputsEnabled(BpodSystem.HW.Inputs == 'P')) == 0
                warning(['All Bpod behavior ports are currently disabled.'...
                    'If your protocol requires behavior ports, enable them from the settings menu.'])
            end
            run(protocolPath);
        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
            LaunchManager;
        end

        % Value changed function: SettingsTextArea
        function SettingsTextAreaValueChanged(app, event)
            app.checkAllTextAreas;


        end

        % Value changed function: ProtocolTextArea
        function ProtocolTextAreaValueChanged(app, event)
            app.checkAllTextAreas;

        end

        % Value changed function: SubjectTextArea
        function SubjectTextAreaValueChanged(app, event)
            app.checkAllTextAreas;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SubjectTextAreaLabel = uilabel(app.UIFigure);
            app.SubjectTextAreaLabel.Text = 'Subject';
            app.SubjectTextArea = uitextarea(app.UIFigure);
            app.SubjectTextArea.ValueChangedFcn = createCallbackFcn(app, @SubjectTextAreaValueChanged, true);
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Text = 'START';
            app.ProtocolTextAreaLabel = uilabel(app.UIFigure);
            app.ProtocolTextAreaLabel.Text = 'Protocol';
            app.ProtocolTextArea = uitextarea(app.UIFigure);
            app.ProtocolTextArea.ValueChangedFcn = createCallbackFcn(app, @ProtocolTextAreaValueChanged, true);
            app.ProtocolTextArea.Editable = 'off';
            app.ProtocolTextArea.Value = {'PuffyPenguin'};
            app.ManualButton = uibutton(app.UIFigure, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Text = 'Manual';
            app.SettingsTextAreaLabel = uilabel(app.UIFigure);
            app.SettingsTextAreaLabel.Text = 'Settings';
            app.SettingsTextArea = uitextarea(app.UIFigure);
            app.SettingsTextArea.ValueChangedFcn = createCallbackFcn(app, @SettingsTextAreaValueChanged, true);
            app.SettingsTextArea.Editable = 'off';
            app.SettingsTextArea.Value = {'DefaultSettings'};
            app.READYButton = uibutton(app.UIFigure, 'push');
            app.READYButton.ButtonPushedFcn = createCallbackFcn(app, @READYButtonPushed, true);
            app.READYButton.Text = 'READY';
            app.WeightTextAreaLabel = uilabel(app.UIFigure);
            app.WeightTextAreaLabel.Text = 'Weight';
            app.WeightTextArea = uitextarea(app.UIFigure);
            app.WeightTextArea.Editable = 'off';
            app.WeighagainButton = uibutton(app.UIFigure, 'push');
            app.WeighagainButton.ButtonPushedFcn = createCallbackFcn(app, @WeighagainButtonPushed, true);
            app.WeighagainButton.Text = 'Weigh again';
            app.LocalIPLabel = uilabel(app.UIFigure);
            app.LocalIPLabel.Text = 'Local IP:';
            app.LocalIPTextArea = uitextarea(app.UIFigure);
            app.LocalIPTextArea.Editable = 'off';
            app.LocalIPTextArea.Value = {'134.130.63.116'};
            app.LocalPortTextAreaLabel = uilabel(app.UIFigure);
            app.LocalPortTextAreaLabel.Text = 'Local Port:';
            app.LocalPortTextArea = uitextarea(app.UIFigure);
            app.LocalPortTextArea.Editable = 'off';
            app.LocalPortTextArea.Value = {'5001'};
            app.ServerIPTextAreaLabel = uilabel(app.UIFigure);
            app.ServerIPTextAreaLabel.Text = 'Server IP:';
            app.ServerIPTextArea = uitextarea(app.UIFigure);
            app.ServerIPTextArea.Editable = 'off';
            app.ServerIPTextArea.Value = {'134.130.63.141'};
            app.ServerPortTextAreaLabel = uilabel(app.UIFigure);
            app.ServerPortTextAreaLabel.Text = 'Server Port:';
            app.ServerPortTextArea = uitextarea(app.UIFigure);
            app.ServerPortTextArea.Editable = 'off';
            app.ServerPortTextArea.Value = {'5002'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AutoPilot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end