classdef SpoutAdjustment_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SpoutadjustmentPanel     matlab.ui.container.Panel
        ContinueButton           matlab.ui.control.Button
        spoutOffset              matlab.ui.control.NumericEditField
        OffsetLabel_2            matlab.ui.control.Label
        RightSpoutEdit           matlab.ui.control.NumericEditField
        RightEditFieldLabel_2    matlab.ui.control.Label
        LeftSpoutEdit            matlab.ui.control.NumericEditField
        LeftEditFieldLabel_2     matlab.ui.control.Label
        WaterDispenserSpinner    matlab.ui.control.Spinner
        GiveBothWater            matlab.ui.control.Button
        GiveLeftWater            matlab.ui.control.Button
        GiveRightWater           matlab.ui.control.Button
        spoutspeed               matlab.ui.control.Spinner
        MovementdurationmsSpinnerLabel_2  matlab.ui.control.Label
        spouttoinner             matlab.ui.control.Button
        spouttoouter             matlab.ui.control.Button
        spouttozero              matlab.ui.control.Button
        KnobmaxEditField         matlab.ui.control.NumericEditField
        KnobmaxEditField_2Label  matlab.ui.control.Label
        SpoutRightKnob           matlab.ui.control.Knob
        RightspoutKnobLabel_2    matlab.ui.control.Label
        SpoutLeftKnob            matlab.ui.control.Knob
        LeftspoutKnobLabel_2     matlab.ui.control.Label
        SpoutMirrorspouts        matlab.ui.control.CheckBox
        SpoutsetouterButton      matlab.ui.control.Button
        SpoutsetinnerButton      matlab.ui.control.Button
    end

    methods (Access = public)

        function getSettingsFromBpod(app)
            set(app.PauseSwitch,'Value','pause')
            global BpodSystem
            S = BpodSystem.ProtocolSettings;
            set(app.Subjectname,'Text',S.SubjectName)
            if isfield(BpodSystem.Data,'animalWeight')
                set(app.WeightgSpinner,'Value',BpodSystem.Data.animalWeight)
            end
            set(app.WeightgSpinnerLabel,'FontColor','r')
            set(app.timeElapsedLabel,'Text','0.0 min')
            set(app.StopButton,'Text','Stop')
            set(app.Water,'Text','0.0 mL')
            set(app.Trial,'Text','initialized')
            %spouts and handles
            set(app.spoutspeed,'Value',BpodSystem.ProtocolSettings.SpoutSpeed)

            % touch thresh
            set(app.TouchthresholdsdSpinner,'Value',BpodSystem.ProtocolSettings.TouchThresh)
            %training mode
            set(app.TrainingmodeCheckBox,'Value',BpodSystem.ProtocolSettings.TrainingMode)
            set(app.AutorewardCheckBox,'Value',BpodSystem.ProtocolSettings.AutoReward)
            set(app.fractionmixedSpinner,'Value', BpodSystem.ProtocolSettings.fractionTrainingMixed)
            set(app.fractionaudioSpinner, 'Value', BpodSystem.ProtocolSettings.fractionTrainingAudio)
            set(app.fractionvisionSpinner, 'Value',BpodSystem.ProtocolSettings.fractionTrainingVision)
            set(app.fractiontactileSpinner, 'Value',BpodSystem.ProtocolSettings.fractionTrainingTactile)

            % modality/task
            set(app.ModalityDropDown,'Value',BpodSystem.ProtocolSettings.RewardedModality)
            set(app.pRightSpinner,'Value',BpodSystem.ProtocolSettings.ProbRight)
            set(app.pAudioSpinner,'Value',BpodSystem.ProtocolSettings.ProbAudio)
            set(app.pVisualSpinner,'Value',BpodSystem.ProtocolSettings.ProbVision)
            set(app.VisualdistractorCheckBox,'Value',BpodSystem.ProtocolSettings.useDistVisual)
            set(app.AudiodistractorCheckBox,'Value',BpodSystem.ProtocolSettings.useDistAudio)
            set(app.SomatossensorydistractorCheckBox,'Value',BpodSystem.ProtocolSettings.useDistTactile)

            set(app.pSomatosensorySpinner,'Value',BpodSystem.ProtocolSettings.ProbTactile)
            set(app.pDistSpinner,'Value',BpodSystem.ProtocolSettings.DistProb)
            set(app.StimrateSpinner,'Value',BpodSystem.ProtocolSettings.StimRate)
            set(app.targetEditField,'Value',num2str(BpodSystem.ProtocolSettings.TargFractions))
            set(app.distractorEditField,'Value',num2str(BpodSystem.ProtocolSettings.DistFractions))
            set(app.StimulidurationSpinner,'Value',BpodSystem.ProtocolSettings.StimDuration)
            set(app.PunishdurationSpinner,'Value',BpodSystem.ProtocolSettings.PunishSoundDur)
            set(app.MaxtrialsonsamesideSpinner,'Value',BpodSystem.ProtocolSettings.biasSeqLength)
            set(app.distDifficulties,'Value',num2str(BpodSystem.ProtocolSettings.distDifficulties))
            set(app.maxDetectionSequence,'Value',logical(BpodSystem.ProtocolSettings.maxDetectionSequence))
            set(app.contingencyReversal,'Value',logical(BpodSystem.ProtocolSettings.contingencyReversal))

            set(app.TrialstartsoundCheckBox,'Value',BpodSystem.ProtocolSettings.TrialStartCue)
            set(app.TimetochooseSpinner,'Value',BpodSystem.ProtocolSettings.TimeToChoose)
            set(app.TimetoconfirmSpinner,'Value',BpodSystem.ProtocolSettings.TimeToConfirm)
            set(app.HappytimeSpinner,'Value',BpodSystem.ProtocolSettings.happyTime)
            set(app.TimeoutSpinner,'Value',BpodSystem.ProtocolSettings.TimeOut)
            set(app.rewardSizeSpinner,'Value',BpodSystem.ProtocolSettings.leftRewardVolume)
            set(app.ITI_scale,'Value',BpodSystem.ProtocolSettings.ITI_scale)
            set(app.prestimdelay,'Value',BpodSystem.ProtocolSettings.preStimDelay)
            set(app.Delay2valuesforminmaxEditField,'Value',num2str(BpodSystem.ProtocolSettings.DecisionGap))
            set(app.StimCueCheckBox ,'Value',BpodSystem.ProtocolSettings.UseStimStartCue)
            set(app.spoutOffset ,'Value',BpodSystem.ProtocolSettings.spoutOffset)

            if app.FlashdurmsSpinner.Limits(2) < BpodSystem.ProtocolSettings.FlashDuration
                app.FlashdurmsSpinner.Limits(2) = BpodSystem.ProtocolSettings.FlashDuration + 1;
            end
            set(app.FlashdurmsSpinner,'Value',BpodSystem.ProtocolSettings.FlashDuration)
            set(app.BeepdurmsSpinner,'Value',BpodSystem.ProtocolSettings.BeepDuration)
            set(app.PuffdurmsSpinner,'Value',BpodSystem.ProtocolSettings.BuzzDuration)

            set(app.StimulusscreensEditField,'Value', num2str(BpodSystem.ProtocolSettings.stimScreens))
            set(app.UseblackscreenCheckBox,'Value', BpodSystem.ProtocolSettings.blackScreen)
            set(app.ShowWaterCheckBox,'Value', BpodSystem.ProtocolSettings.showWater)

            set(app.brightnessSpinner,'Value',BpodSystem.ProtocolSettings.StimBrightness)
            set(app.loudnessSpinner,'Value',BpodSystem.ProtocolSettings.StimLoudness)
            set(app.puffnessSpinner,'Value',BpodSystem.ProtocolSettings.BuzzStrength)

            if isfield(BpodSystem.ProtocolSettings,'labcamsWidefield')
                set(app.widefieldaddressEditField,'Value',BpodSystem.ProtocolSettings.labcamsWidefield)
            end

            set(app.labcamsaddressEditField,'Value',BpodSystem.ProtocolSettings.labcamsAddress)
            set(app.ITI_scale,'Value',BpodSystem.ProtocolSettings.ITI_scale)
            set(app.ITI_shape,'Value',BpodSystem.ProtocolSettings.ITI_shape)

            if ~isfield(BpodSystem.ProtocolSettings,'SaveSettings')
                BpodSystem.ProtocolSettings.SaveSettings = true;
            end
            set(app.SavesettingsCheckBox,'Value',BpodSystem.ProtocolSettings.SaveSettings);
            set(app.ServerpathEditField,'Value',BpodSystem.ProtocolSettings.serverPath);

            %optogenetics
            set(app.optofractiontrialsEditField,'Value',BpodSystem.ProtocolSettings.optoProb)
            set(app.optodurationEditField,'Value',BpodSystem.ProtocolSettings.optoDur)
            set(app.optoperiodDropDown,'Value',BpodSystem.ProtocolSettings.optoPeriod)
            set(app.optoamp1EditField,'Value',BpodSystem.ProtocolSettings.optoAmp1)
            set(app.optoamp2EditField,'Value',BpodSystem.ProtocolSettings.optoAmp2)
            set(app.optoRampEditField,'Value',BpodSystem.ProtocolSettings.optoRamp)
            set(app.optoLocation,'Value',BpodSystem.ProtocolSettings.optoLocation)
            set(app.optoProbRight,'Value',BpodSystem.ProtocolSettings.optoRight)
            set(app.optoProbBoth,'Value',BpodSystem.ProtocolSettings.optoBoth)
            set(app.optoSeqActive,'Value',BpodSystem.ProtocolSettings.optoSeqActive)
            set(app.optoSeqUnilateral,'Value',BpodSystem.ProtocolSettings.optoSeqUnilateral)
            set(app.optoSeqTrials,'Value',BpodSystem.ProtocolSettings.optoSeqTrials)
            set(app.optoSeqInterval,'Value',BpodSystem.ProtocolSettings.optoSeqInterval)
            set(app.optoSeqStartTime,'Value',BpodSystem.ProtocolSettings.optoSeqStartTime)

            %try
            %    start(app.bpodtimer)
            %catch
            %app.bpodtimer = timer('Period',0.033,...
            %   'ExecutionMode', 'fixedSpacing', ...
            %   'TasksToExecute', Inf);
            %app.bpodtimer.TimerFcn = @app.timerfunction;
            %start(app.bpodtimer)
            %end

        end

    function results = moveSpouts(app,l,r)
        set(app.LeftSpoutEdit,'Value',l); %update indicator
        set(app.SpoutLeftKnob,'Value',l); %update slider
        set(app.RightSpoutEdit,'Value',r); %update indicator
        set(app.SpoutRightKnob,'Value',r);  %update slider
        ls = num2str(l);rs = num2str(r); drawnow;
        teensyWrite([71 length(ls)  ls length(rs)  rs]); %move the spouts
    end

    function results = sendTeensyStepperTrialInfo(app)
        % this might be simplified in the teeny side..
        global BpodSystem
        S = BpodSystem.ProtocolSettings;
        setMotorPositions;

    end

    function results = moveHandles(app,v)
        set(app.HandlepositionKnob,'Value',v); %update slider
        v = num2str(int32(floor(v)));
        teensyWrite([72 length(v)  v]); %move the spouts
    end
end
methods (Access = private)
    function results = giveWater(app,valve)
        global BpodSystem

        waterAmount = app.WaterDispenserSpinner.Value; % give water for the regular time
        LeftValveTime = GetValveTimes(waterAmount, 1);
        RightValveTime = GetValveTimes(waterAmount, 2);

        if strcmp(valve,'left')
            valve = 1;
            cValveDur = LeftValveTime;
        elseif strcmp(valve,'right')
            valve = 2;
            cValveDur = RightValveTime;
        else
            valve = 3;
            cValveDur = max([LeftValveTime,RightValveTime]);
        end

        if ~BpodSystem.Status.InStateMatrix
            smavalve = NewStateMachine;
            smavalve = AddState(smavalve, 'Name', 'test', ...
                'Timer', cValveDur,...
                'StateChangeConditions', {'Tup', 'exit'},...
                'OutputActions', {'ValveState', valve}); % Use 3 for valve 1 and 2 (1100) 5 for (1010)

            SendStateMachine(smavalve);
            pause(0.1);
            RunStateMachine();

        end
    end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global BpodSystem
            BpodSystem.Status.PuffyPenguinExit = false;
            PuffyPenguin_Settings; %script to define default settings if they are not defined by settings file
            PuffyPenguin_InitSpouts;
        end

        % Button pushed function: GiveLeftWater
        function GiveLeftWaterButtonPushed(app, event)
            app.giveWater('left');
        end

        % Button pushed function: GiveRightWater
        function GiveRightWaterButtonPushed(app, event)
            app.giveWater('right');
        end

        % Value changed function: SpoutLeftKnob
        function SpoutLeftKnobValueChanged(app, event)
            value = app.SpoutLeftKnob.Value;
            set(app.LeftSpoutEdit,'Value',value);
            if app.SpoutMirrorspouts.Value
                set(app.SpoutRightKnob,'Value',value);
            end
            app.moveSpouts(app.SpoutLeftKnob.Value,app.SpoutRightKnob.Value);
        end

        % Value changing function: SpoutLeftKnob
        function SpoutLeftKnobValueChanging(app, event)
            valuel = ceil(event.Value);
            valuer = ceil(event.Value);
            set(app.LeftSpoutEdit,'Value',valuel);
            if app.SpoutMirrorspouts.Value == false
                valuer = app.SpoutRightKnob.Value;
            end
            set(app.RightSpoutEdit,'Value',valuer);
            app.moveSpouts(valuel,valuer);
        end

        % Value changed function: SpoutRightKnob
        function SpoutRightKnobValueChanged(app, event)
            value = app.SpoutRightKnob.Value;
            set(app.RightSpoutEdit,'Value',value);
            if app.SpoutMirrorspouts.Value
                set(app.SpoutLeftKnob,'Value',value);
            end
            app.moveSpouts(app.SpoutLeftKnob.Value,app.SpoutRightKnob.Value);
        end

        % Value changing function: SpoutRightKnob
        function SpoutRightKnobValueChanging(app, event)
            valuel = ceil(event.Value);
            valuer = ceil(event.Value);
            set(app.RightSpoutEdit,'Value',valuer);
            if app.SpoutMirrorspouts.Value == false
                valuel = app.SpoutLeftKnob.Value;
            end
            set(app.LeftSpoutEdit,'Value',valuel);
            app.moveSpouts(valuel,valuer);
        end

        % Value changed function: LeftSpoutEdit
        function LeftSpoutEditValueChanged(app, event)
            value = app.LeftSpoutEdit.Value;
            if value ~= app.SpoutLeftKnob.Value
                set(app.SpoutLeftKnob,'Value',value);
            end; drawnow;
            app.moveSpouts(app.SpoutLeftKnob.Value,app.SpoutRightKnob.Value);
        end

        % Value changed function: RightSpoutEdit
        function RightSpoutEditValueChanged(app, event)
            value = app.RightSpoutEdit.Value;
            if value ~= app.SpoutRightKnob.Value
                set(app.SpoutRightKnob,'Value',value);
            end; drawnow;
            app.moveSpouts(app.SpoutLeftKnob.Value,app.SpoutRightKnob.Value);
        end

        % Button pushed function: spouttozero
        function spouttozeroButtonPushed(app, event)
            moveSpouts(app,0,0)
        end

        % Button pushed function: spouttoouter
        function spouttoouterButtonPushed(app, event)
            global BpodSystem
            leftSpout = BpodSystem.ProtocolSettings.lOuterLim; %get left inner limit
            rightSpout = BpodSystem.ProtocolSettings.rOuterLim; %get right inner limit
            app.moveSpouts(leftSpout,rightSpout);
        end

        % Button pushed function: SpoutsetouterButton
        function SpoutsetouterButtonPushed(app, event)
            global BpodSystem
            BpodSystem.ProtocolSettings.lOuterLim = ceil(app.SpoutLeftKnob.Value); %get left inner limit
            BpodSystem.ProtocolSettings.rOuterLim = ceil(app.SpoutRightKnob.Value); %get right inner limit
            disp('Set outer positions')
        end

        % Button pushed function: spouttoinner
        function spouttoinnerButtonPushed(app, event)
            global BpodSystem
            leftSpout = BpodSystem.ProtocolSettings.lInnerLim; %get left inner limit
            rightSpout = BpodSystem.ProtocolSettings.rInnerLim; %get right inner limit
            app.moveSpouts(leftSpout,rightSpout)
        end

        % Button pushed function: SpoutsetinnerButton
        function SpoutsetinnerButtonPushed(app, event)
            global BpodSystem
            BpodSystem.ProtocolSettings.lInnerLim = ceil(app.SpoutLeftKnob.Value); %get left inner limit
            BpodSystem.ProtocolSettings.rInnerLim = ceil(app.SpoutRightKnob.Value); %get right inner limit
            disp('Set inner positions')
        end

        % Button pushed function: GiveBothWater
        function GiveBothWaterButtonPushed(app, event)
            app.giveWater('both');
        end

        % Value changed function: KnobmaxEditField
        function KnobmaxEditFieldValueChanged(app, event)
            value = app.KnobmaxEditField.Value;
            set(app.SpoutLeftKnob,'Limits',[0,value]);
            set(app.SpoutRightKnob,'Limits',[0,value]);
        end

        % Value changed function: spoutspeed
        function spoutspeedValueChanged(app, event)
            value = app.spoutspeed.Value;
            global BpodSystem
            app.sendTeensyStepperTrialInfo();
            BpodSystem.ProtocolSettings.SpoutSpeed = value; %update SpoutSpeed
            teensyWrite([73 length(num2str(value)) num2str(value)]); %set spout speed
        end

        % Value changed function: spoutOffset
        function spoutOffsetValueChanged(app, event)
            value = app.spoutOffset.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.spoutOffset = value;
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            global BpodSystem
            app.delete;
            BpodSystem.GUIHandles.AutoPilot = AutoPilot;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpoutadjustmentPanel = uipanel(app.UIFigure);
            app.SpoutadjustmentPanel.Title = 'Spout adjustment';
            app.SpoutsetinnerButton = uibutton(app.SpoutadjustmentPanel, 'push');
            app.SpoutsetinnerButton.ButtonPushedFcn = createCallbackFcn(app, @SpoutsetinnerButtonPushed, true);
            app.SpoutsetinnerButton.Text = 'set';
            app.SpoutsetouterButton = uibutton(app.SpoutadjustmentPanel, 'push');
            app.SpoutsetouterButton.ButtonPushedFcn = createCallbackFcn(app, @SpoutsetouterButtonPushed, true);
            app.SpoutsetouterButton.Text = 'set';
            app.SpoutMirrorspouts = uicheckbox(app.SpoutadjustmentPanel);
            app.SpoutMirrorspouts.Text = 'Mirror movement';
            app.SpoutMirrorspouts.Value = true;
            app.LeftspoutKnobLabel_2 = uilabel(app.SpoutadjustmentPanel);
            app.LeftspoutKnobLabel_2.Text = 'Left spout';
            app.SpoutLeftKnob = uiknob(app.SpoutadjustmentPanel, 'continuous');
            app.SpoutLeftKnob.Limits = [0 150];
            app.SpoutLeftKnob.ValueChangedFcn = createCallbackFcn(app, @SpoutLeftKnobValueChanged, true);
            app.SpoutLeftKnob.ValueChangingFcn = createCallbackFcn(app, @SpoutLeftKnobValueChanging, true);
            app.RightspoutKnobLabel_2 = uilabel(app.SpoutadjustmentPanel);
            app.RightspoutKnobLabel_2.Text = 'Right spout';
            app.SpoutRightKnob = uiknob(app.SpoutadjustmentPanel, 'continuous');
            app.SpoutRightKnob.Limits = [0 150];
            app.SpoutRightKnob.ValueChangedFcn = createCallbackFcn(app, @SpoutRightKnobValueChanged, true);
            app.SpoutRightKnob.ValueChangingFcn = createCallbackFcn(app, @SpoutRightKnobValueChanging, true);
            app.KnobmaxEditField_2Label = uilabel(app.SpoutadjustmentPanel);
            app.KnobmaxEditField_2Label.Text = 'Knob max';
            app.KnobmaxEditField = uieditfield(app.SpoutadjustmentPanel, 'numeric');
            app.KnobmaxEditField.ValueChangedFcn = createCallbackFcn(app, @KnobmaxEditFieldValueChanged, true);
            app.KnobmaxEditField.Value = 150;
            app.spouttozero = uibutton(app.SpoutadjustmentPanel, 'push');
            app.spouttozero.ButtonPushedFcn = createCallbackFcn(app, @spouttozeroButtonPushed, true);
            app.spouttozero.Text = 'zero';
            app.spouttoouter = uibutton(app.SpoutadjustmentPanel, 'push');
            app.spouttoouter.ButtonPushedFcn = createCallbackFcn(app, @spouttoouterButtonPushed, true);
            app.spouttoouter.Text = 'outer';
            app.spouttoinner = uibutton(app.SpoutadjustmentPanel, 'push');
            app.spouttoinner.ButtonPushedFcn = createCallbackFcn(app, @spouttoinnerButtonPushed, true);
            app.spouttoinner.Text = 'inner';
            app.MovementdurationmsSpinnerLabel_2 = uilabel(app.SpoutadjustmentPanel);
            app.MovementdurationmsSpinnerLabel_2.Text = 'Movement duration (ms)';
            app.spoutspeed = uispinner(app.SpoutadjustmentPanel);
            app.spoutspeed.Step = 5;
            app.spoutspeed.Limits = [0 5000];
            app.spoutspeed.ValueChangedFcn = createCallbackFcn(app, @spoutspeedValueChanged, true);
            app.spoutspeed.Value = 25;
            app.GiveRightWater = uibutton(app.SpoutadjustmentPanel, 'push');
            app.GiveRightWater.ButtonPushedFcn = createCallbackFcn(app, @GiveRightWaterButtonPushed, true);
            app.GiveRightWater.Text = 'Give water';
            app.GiveLeftWater = uibutton(app.SpoutadjustmentPanel, 'push');
            app.GiveLeftWater.ButtonPushedFcn = createCallbackFcn(app, @GiveLeftWaterButtonPushed, true);
            app.GiveLeftWater.Text = 'Give water';
            app.GiveBothWater = uibutton(app.SpoutadjustmentPanel, 'push');
            app.GiveBothWater.ButtonPushedFcn = createCallbackFcn(app, @GiveBothWaterButtonPushed, true);
            app.GiveBothWater.Text = 'Give water';
            app.WaterDispenserSpinner = uispinner(app.SpoutadjustmentPanel);
            app.WaterDispenserSpinner.Limits = [1 100];
            app.WaterDispenserSpinner.Value = 2;
            app.LeftEditFieldLabel_2 = uilabel(app.SpoutadjustmentPanel);
            app.LeftEditFieldLabel_2.Text = 'Left';
            app.LeftSpoutEdit = uieditfield(app.SpoutadjustmentPanel, 'numeric');
            app.LeftSpoutEdit.ValueChangedFcn = createCallbackFcn(app, @LeftSpoutEditValueChanged, true);
            app.RightEditFieldLabel_2 = uilabel(app.SpoutadjustmentPanel);
            app.RightEditFieldLabel_2.Text = 'Right';
            app.RightSpoutEdit = uieditfield(app.SpoutadjustmentPanel, 'numeric');
            app.RightSpoutEdit.ValueChangedFcn = createCallbackFcn(app, @RightSpoutEditValueChanged, true);
            app.OffsetLabel_2 = uilabel(app.SpoutadjustmentPanel);
            app.OffsetLabel_2.Text = 'spoutOffset';
            app.spoutOffset = uieditfield(app.SpoutadjustmentPanel, 'numeric');
            app.spoutOffset.Limits = [1 50];
            app.spoutOffset.ValueChangedFcn = createCallbackFcn(app, @spoutOffsetValueChanged, true);
            app.spoutOffset.Value = 20;
            app.ContinueButton = uibutton(app.SpoutadjustmentPanel, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpoutAdjustment_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end