classdef WeighingSystemGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        WeighagainButton                matlab.ui.control.Button
        WeightTextArea                  matlab.ui.control.TextArea
        WeightTextAreaLabel             matlab.ui.control.Label
        ReadRFIDandweighButton          matlab.ui.control.Button
        SessionsettingsTextArea         matlab.ui.control.TextArea
        SessionsettingsTextAreaLabel    matlab.ui.control.Label
        ParametersTextArea              matlab.ui.control.TextArea
        ParametersTextAreaLabel         matlab.ui.control.Label
        MakeadjustmentsButton           matlab.ui.control.Button
        SuggestedparadigmTextArea       matlab.ui.control.TextArea
        SuggestedparadigmTextAreaLabel  matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        NewAnimalButton                 matlab.ui.control.Button
        StartsuggestedparadigmButton    matlab.ui.control.Button
        MouseIDTextArea                 matlab.ui.control.TextArea
        MouseIDTextAreaLabel            matlab.ui.control.Label
    end

methods (Access = private)

    function updateWeightTextArea(app)
            file_path = 'C:\Users\laufs\Desktop\AutoPilot\StartButton\mean_value.txt';
            
            if isfile(file_path)
                mean_value = fileread(file_path);
                app.WeightTextArea.Value = mean_value;
                % Clear the file after reading its content
                fid = fopen(file_path, 'w');
                fclose(fid);
            else
                app.WeightTextArea.Value = 'File not found';
            end
        end

        function updateMouseIDTextArea(app)
            rfid_path = 'C:\Users\laufs\Desktop\AutoPilot\StartButton\RFID_value.txt';
            
            if isfile(rfid_path)
                rfid_value = fileread(rfid_path);
                app.MouseIDTextArea.Value = rfid_value;
                % Clear the file after reading its content
                fid = fopen(rfid_path, 'w');
                fclose(fid);
            else
                app.MouseIDTextArea.Value = 'File not found';
            end
        end

        function waitForFlagFile(~, flag_type)
            flag_file_path = ['C:\Users\laufs\Desktop\AutoPilot\StartButton\' flag_type '.txt'];

            while ~isfile(flag_file_path)
                pause(0.5);
            end

            delete(flag_file_path);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadRFIDandweighButton
        function ReadRFIDandweighButtonPushed(app, event)
% Create the start signal file to trigger the Python script
            start_signal_path = 'C:\Users\laufs\Desktop\AutoPilot\StartButton\start_signal.txt';
            fid = fopen(start_signal_path, 'w');
            if fid ~= -1
                fprintf(fid, 'start');
                fclose(fid);
                disp('Start signal created');
            else
                error('Unable to create start signal file');
            end

            % Wait for the Python script to process and write the RFID value
            app.waitForFlagFile('ID_ready');
            app.updateMouseIDTextArea();

            % Wait for the Python script to process and write the mean value
            app.waitForFlagFile('mean_ready');
            app.updateWeightTextArea();
        end

        % Button pushed function: WeighagainButton
        function WeighagainButtonPushed(app, event)
            % Create the start signal file to trigger the Python script
            start_signal_path = 'C:\Users\laufs\Desktop\AutoPilot\StartButton\weigh_signal.txt';
            fid = fopen(start_signal_path, 'w');
            if fid ~= -1
                fprintf(fid, 'start');
                fclose(fid);
                disp('Start signal created');
            else
                error('Unable to create start signal file');
            end

             % Wait for the Python script to process and write the mean value
            app.waitForFlagFile('mean_ready');
            app.updateWeightTextArea();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MouseIDTextAreaLabel = uilabel(app.UIFigure);
            app.MouseIDTextAreaLabel.Text = 'Mouse ID';
            app.MouseIDTextArea = uitextarea(app.UIFigure);
            app.MouseIDTextArea.Editable = 'off';
            app.StartsuggestedparadigmButton = uibutton(app.UIFigure, 'push');
            app.StartsuggestedparadigmButton.Text = 'Start suggested paradigm';
            app.NewAnimalButton = uibutton(app.UIFigure, 'push');
            app.NewAnimalButton.Text = 'New Animal';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            app.SuggestedparadigmTextAreaLabel = uilabel(app.UIFigure);
            app.SuggestedparadigmTextAreaLabel.Text = 'Suggested paradigm';
            app.SuggestedparadigmTextArea = uitextarea(app.UIFigure);
            app.SuggestedparadigmTextArea.Editable = 'off';
            app.MakeadjustmentsButton = uibutton(app.UIFigure, 'push');
            app.MakeadjustmentsButton.Text = 'Make adjustments';
            app.ParametersTextAreaLabel = uilabel(app.UIFigure);
            app.ParametersTextAreaLabel.Text = 'Parameters';
            app.ParametersTextArea = uitextarea(app.UIFigure);
            app.ParametersTextArea.Editable = 'off';
            app.SessionsettingsTextAreaLabel = uilabel(app.UIFigure);
            app.SessionsettingsTextAreaLabel.Text = 'Session settings';
            app.SessionsettingsTextArea = uitextarea(app.UIFigure);
            app.SessionsettingsTextArea.Editable = 'off';
            app.ReadRFIDandweighButton = uibutton(app.UIFigure, 'push');
            app.ReadRFIDandweighButton.ButtonPushedFcn = createCallbackFcn(app, @ReadRFIDandweighButtonPushed, true);
            app.ReadRFIDandweighButton.Text = 'Read RFID and weigh';
            app.WeightTextAreaLabel = uilabel(app.UIFigure);
            app.WeightTextAreaLabel.Text = 'Weight';
            app.WeightTextArea = uitextarea(app.UIFigure);
            app.WeightTextArea.Editable = 'off';
            app.WeighagainButton = uibutton(app.UIFigure, 'push');
            app.WeighagainButton.ButtonPushedFcn = createCallbackFcn(app, @WeighagainButtonPushed, true);
            app.WeighagainButton.Text = 'Weigh again';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WeighingSystemGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end