classdef AutoPilot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AutopilotUIFigure             matlab.ui.Figure
        WidefieldimagingCheckBox      matlab.ui.control.CheckBox
        OptogenTextAreaLabel          matlab.ui.control.Label
        OptogenTextArea               matlab.ui.control.TextArea
        PhotometryTextArea_2Label     matlab.ui.control.Label
        PhotometryTextArea            matlab.ui.control.TextArea
        EPhysTextArea                 matlab.ui.control.TextArea
        EPhysTextAreaLabel            matlab.ui.control.Label
        OSKButton                     matlab.ui.control.Button
        ChangeServerButton            matlab.ui.control.Button
        CalibrateScaleLabel           matlab.ui.control.Label
        CalibrationButton             matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        enableSubject                 matlab.ui.control.Switch
        TareScaleLabel                matlab.ui.control.Label
        TareButton                    matlab.ui.control.Button
        CurrentWeightgTextArea        matlab.ui.control.TextArea
        CurrentWeightgLabel           matlab.ui.control.Label
        LoadDataButton                matlab.ui.control.Button
        RFIDTextArea                  matlab.ui.control.TextArea
        RFIDTextAreaLabel             matlab.ui.control.Label
        ServerPortTextArea            matlab.ui.control.TextArea
        ServerPortTextAreaLabel       matlab.ui.control.Label
        ServerIPTextArea              matlab.ui.control.TextArea
        ServerIPTextAreaLabel         matlab.ui.control.Label
        LocalPortTextArea             matlab.ui.control.TextArea
        LocalPortTextAreaLabel        matlab.ui.control.Label
        LocalIPTextArea               matlab.ui.control.TextArea
        LocalIPLabel                  matlab.ui.control.Label
        MeasuredWeightgTextArea       matlab.ui.control.TextArea
        MeasuredWeightgTextAreaLabel  matlab.ui.control.Label
        WeighButton                   matlab.ui.control.Button
        SettingsTextArea              matlab.ui.control.TextArea
        SettingsTextAreaLabel         matlab.ui.control.Label
        ManualButton                  matlab.ui.control.Button
        ProtocolTextArea              matlab.ui.control.TextArea
        ProtocolTextAreaLabel         matlab.ui.control.Label
        STARTButton                   matlab.ui.control.Button
        SubjectTextArea               matlab.ui.control.TextArea
        SubjectTextAreaLabel          matlab.ui.control.Label
    end

    properties (Access = public)
        Timer % Timer object
        Running % Flag to control the while loop
        pushed_start % flag for pushing the start button
        push_calibrate % flag for pushing calibration button
        scalePort
        arduinoPort
        hostname
        scaleSerial
        arduino
    end

    methods (Access = public)
        % Function to check if all text areas have text
        function checkAllTextAreas(app)

            % Get the values of the text areas
            value1 = char(app.ProtocolTextArea.Value);
            value2 = char(app.SettingsTextArea.Value);
            value3 = char(app.SubjectTextArea.Value);
            value4 = char(app.MeasuredWeightgTextArea.Value);

            % Remove leading and trailing whitespace
            value1 = strtrim(value1);
            value2 = strtrim(value2);
            value3 = strtrim(value3);
            value4 = strtrim(value4);

            % Check if TextArea1 contains words (alphabetic characters)
            containsWords = ~isempty(value1) && any(isletter(value1));

            % Check if TextArea2 contains words (alphabetic characters)
            containsWords2 = ~isempty(value2) && any(isletter(value2));

            % Check if TextArea3 contains numbers (numeric characters)
            containsNumbers = ~isempty(value3) && all(isstrprop(value3, 'digit'));

            % Check if TextArea4 contains numbers (float characters)
            numValue = str2double(value4);
            containsFloat = ~isnan(numValue) && isfloat(numValue);
            % Enable or disable the button based on the specific checks
            if containsWords && containsWords2 && containsNumbers && containsFloat
                app.STARTButton.Enable = 'on';
            else
                app.STARTButton.Enable = 'off';
            end
        end

        function checkWeight(app)

            % Get the values of the text areas
            value1 = char(app.MeasuredWeightgTextArea.Value);
            value2 = char(app.RFIDTextArea.Value);
            value3 = char(app.SubjectTextArea.Value);

            % Remove leading and trailing whitespace
            value1 = strtrim(value1);
            %value3 = strtrim(value2);

            % Check if TextArea1 contains numbers (float characters)
            numValue = str2double(value1);
            containsFloat = ~isnan(numValue) && isfloat(numValue);


            % Check if TextArea2 contains something
            if strcmp(value2, 'Waiting for RFID...')
                containsSomething = 0;
            elseif strcmp(value2, '-')
                containsSomething = 0;
            else
                containsSomething = 1;
            end

            % Check if TextArea3 contains something
            if ~isempty(value3)
                IDavailable = 1;
            else
                IDavailable = 0;
            end

            % Enable or disable the button based on the specific checks
            if  containsFloat && containsSomething == 1
                app.LoadDataButton.Enable = 'on';
            elseif containsFloat && IDavailable
                app.LoadDataButton.Enable = 'on';
            else
                app.LoadDataButton.Enable = 'off';
            end
        end

        function startupFcn(app)

            % Stop the timer
            stop(app.Timer);
            % Delete the timer
            delete(app.Timer);
            % Stop the timer
            stop(app.timerCallback);

            % Create the timer object
            app.Timer = timer;
            app.Timer.Period = 1; % Execute every 1 second
            app.Timer.ExecutionMode = 'fixedRate'; % Fixed rate execution mode
            app.Timer.TimerFcn = @(~,~) app.timerCallback; % Callback function

            % Start the timer
            start(app.Timer);
        end

        function [paradigm, setting , subjectID, stage,photometry, optogenetic, widefield,e_phys,change_stage] = sendUDP(~,rfid, weight, remoteIP, remotePort ,localIP,localPort)
            paradigm = '';
            setting = '';
            subjectID = '';

            % set up the udp connection
            u = udpport("datagram","LocalHost",localIP, "LocalPort", localPort);

            % Prepare the message
            message = sprintf('ID = %s , weight = %s , NO_ID = 0', rfid, weight);
            disp(message);

            % Send the message
            write(u, message, remoteIP, remotePort);
            timeoutLimit = 5; %Timeout in s %% TODO: we may have to adapt this limit
            tic
            while ~u.NumDatagramsAvailable
                if toc > timeoutLimit
                    disp('Timeout reached. Aborting function.')
                    break
                end
                % TODO: add timer to prevent getting stuck
                drawnow; % Allow MATLAB to process callbacks and graphics updates
            end
            if u.NumDatagramsAvailable > 0
                data = read(u,1,"uint8");  % Read the received datagram
                pause(1);
                fprintf("received...\n");
                receive_bytes = char(data.Data);  % Convert to 32-bit binary string
                pattern = '(\w+)\s*=\s*(\S+)';
                tokens = regexp(receive_bytes, pattern, 'tokens');
                %Extract values from the message
                received_message = struct();
                for i = 1:length(tokens)
                    key = tokens{i}{1};   % Field name
                    value = tokens{i}{2}; % Field value
                    received_message.(key) = value;
                end
                paradigm = received_message.paradigm;
                setting = received_message.setting;
                subjectID = received_message.subjectID;
                stage = received_message.stage;
                photometry = split(received_message.photometry,"-");
                optogenetic = split(received_message.optogenetic,"-");
                widefield = received_message.widefield;
                e_phys = split(received_message.e_phys,"-");
                change_stage = received_message.change_stage;

            end

            % Close the UDP connection
            flush(u,"output");
            %
            clear u;
        end
        function [paradigm, setting , stage,photometry, optogenetic,widefield,e_phys,change_stage] = sendUDP1(~, subject, weight, remoteIP, remotePort ,localIP,localPort)
            paradigm = '';
            setting = '';
            stage = '';
            photometry = '';
            optogenetic = '';
            widefield = '';
            e_phys = '';
            change_stage = '';

            % set up the udp connection
            u = udpport("datagram","LocalHost",localIP, "LocalPort", localPort);

            % Prepare the message
            message = sprintf('subjectID = %s , weight = %s , No_ID = 1', subject, weight);
            disp(message);

            % Send the message
            write(u, message, remoteIP, remotePort);
            timeoutLimit = 20; %Timeout in s
            tic
            while ~u.NumDatagramsAvailable
                if toc > timeoutLimit
                    disp('Timeout reached. Aborting function.')
                    break
                end
                %TODO: we should add a timer here to prevent getting stuck in
                %the loop
                drawnow; % Allow MATLAB to process callbacks and graphics updates
            end
            if u.NumDatagramsAvailable > 0
                data = read(u,1,"uint8");  % Read the received datagram
                pause(1);
                fprintf("received...\n");
                receive_bytes = char(data.Data);  % Convert to 32-bit binary string

                pattern = '(\w+)\s*=\s*(\S+)';
                tokens = regexp(receive_bytes, pattern, 'tokens');
                %Extract values from the message
                received_message = struct();
                for i = 1:length(tokens)
                    key = tokens{i}{1};   % Field name
                    value = tokens{i}{2}; % Field value
                    received_message.(key) = value;
                end
                paradigm = received_message.paradigm;
                setting = received_message.setting;
                stage = received_message.stage;
                photometry = split(received_message.photometry,"-");
                optogenetic = split(received_message.optogenetic,"-");
                widefield = received_message.widefield;
                e_phys = split(received_message.e_phys,"-");
                change_stage = received_message.change_stage;

            end
            % Close the UDP connection
            flush(u,"output");
            %
            clear u;
        end


        function wait_for_settings(app)
            settings = 'Serial Load Cell Converter version 1.2';

            while true
                data = readline(app.scaleSerial);
                if ~isempty(regexp(data, settings, 'once'))
                    % A matching line is found
                    break;
                end
            end
        end

        function wait_for_config(app)
            settings = 'Scale calibration';

            while true
                data = readline(app.scaleSerial);
                if ~isempty(regexp(data, settings, 'once'))
                    % A matching line is found
                    break;
                end
            end
        end

        function skip_lines(app)
            % Define the regex pattern to match
            regexPattern = '\d+,-?\d+\.\d+,[^,]+,\d+\.\d+,';
            settings_end = '>';
            exit = '>Exiting';

            % Initialize flag to indicate if a matching line is found
            foundMatchingLine = false;

            while true
                % Read a line of data
                data = readline(app.scaleSerial);
                % Break the loop if no more lines are received
                if isempty(data)
                    disp('No more lines received. Exiting loop.');
                    break;
                end

                % Check if the line matches the regex pattern
                if ~isempty(regexp(data, regexPattern, 'once'))
                    % A matching line is found
                    foundMatchingLine = true;
                    disp(['Matching line found: ', data]);
                    break;
                end

                if ~isempty(regexp(data, exit, 'once'))
                    break
                end

                if ~isempty(regexp(data, settings_end, 'once'))
                    break
                end
                if ~isempty(regexp(data, '\Calibration Factor: -?\d+' , 'once'))
                    break
                end
                
                if ~isempty(regexp(data, 'Enter the number of decimals to display (0 to 4): -?\d+' , 'once'))
                    break
                end


            end

            % Additional processing can go here if needed
            if ~foundMatchingLine
                disp('No matching line found.');
            end
        end

        function initializeConnections(app)
            try
                % Flag to check if auto-detection is required
                useAutoDetection = false;

                % Check if the USBports.mat file exists
                if exist('USBports.mat', 'file') == 2
                    % Load the saved variable from the file
                    loadedData = load('USBports.mat');
                    app.arduino = loadedData.arduino;
                    app.scaleSerial = loadedData.scaleSerial;

                    % Validate the Arduino connection from the saved data
                    if ~isempty(app.arduino) && isvalid(app.arduino)
                        if ~testConnection(app, app.arduino, 'RFID')
                            disp('Failed to communicate with Arduino from mat file, clearing object...');
                            delete(app.arduino);  % Delete invalid object
                            clear app.arduino;
                            app.arduino = [];
                            useAutoDetection = true;  % Set flag to use auto-detection
                        end
                    else
                        disp('Arduino object from mat file is invalid.');
                        app.arduino = [];
                        useAutoDetection = true;
                    end

                    % Validate the Scale connection from the saved data
                    if ~isempty(app.scaleSerial) && isvalid(app.scaleSerial)
                        if ~testConnection(app, app.scaleSerial, 'OpenScale')
                            disp('Failed to communicate with Scale from mat file, clearing object...');
                            delete(app.scaleSerial);  % Delete invalid object
                            clear app.scaleSerial;
                            app.scaleSerial = [];
                            useAutoDetection = true;  % Set flag to use auto-detection
                        end
                    else
                        disp('Scale object from mat file is invalid.');
                        app.scaleSerial = [];
                        useAutoDetection = true;
                    end
                else
                    disp('USBports.mat does not exist. Starting auto-detection...');
                    useAutoDetection = true;
                end

                % If auto-detection is required, detect available serial ports
                if useAutoDetection
                    disp('Detecting available serial ports...');
                    availablePorts = serialportlist;

                    if isempty(availablePorts)
                        disp('No COM ports detected.');
                        return;
                    else
                        disp('Available COM Ports:');
                        disp(availablePorts);

                        % Loop through available ports to identify devices
                        for i = 1:length(availablePorts)
                            portName = availablePorts{i};
                            try
                                % Create and configure the serialport object
                                serialObj = serialport(portName, 9600, 'Timeout', 2);

                                % Test for Arduino connection
                                if testConnection(app, serialObj, 'RFID')
                                    app.arduino = serialObj;
                                    disp(['Arduino detected on port ', portName]);
                                    % Test for Scale connection
                                elseif testConnection(app, serialObj, 'OpenScale')
                                    app.scaleSerial = serialObj;
                                    disp(['Scale detected on port ', portName]);
                                else
                                    delete(serialObj);  % If not Arduino or Scale, delete the object
                                end
                            catch ME
                                disp(['Error with port ', portName, ': ', ME.message]);
                                if exist('serialObj', 'var')
                                    delete(serialObj);
                                end
                            end
                        end
                    end
                end

                % If connections were not established after auto-detection
                if isempty(app.arduino)
                    disp('Arduino port not detected.');
                end
                if isempty(app.scaleSerial)
                    disp('Scale port not detected.');
                end

                % Flush the serial buffers before continuing
                if ~isempty(app.arduino) && isvalid(app.arduino)
                    flush(app.arduino);
                end
                if ~isempty(app.scaleSerial) && isvalid(app.scaleSerial)
                    flush(app.scaleSerial);
                end

            catch ME
                disp(['Error in initializeConnections: ', ME.message]);
            end
        end

        % Define the testConnection function to test Arduino and Scale connections
        function success = testConnection(app, serialObj, expectedResponse)
            success = false;
            try
                timeout = 5;  % Set a timeout in seconds
                tic;
                while toc < timeout
                    writeline(serialObj, 'ID?');
                    pause(0.5);  % Wait for the device to respond
                    if serialObj.NumBytesAvailable > 0
                        response = readline(serialObj);
                        if contains(response, expectedResponse)
                            disp(['Correct response received: ', expectedResponse]);
                            success = true;
                            break;
                        end
                    end
                end
            catch ME
                disp(['Error communicating with device: ', ME.message]);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function timerCallback(app)
            % Set the flag to true
            app.Running = true;
            global BpodSystem % Import the global BpodSystem object
            global meanWeight

            % Initialize variables
            rfidData = '';
            weightData = [];

            % For Windows:
            if ispc
                [status, cmdout] = system('ipconfig');
                expr = 'IPv4 Address[. ]+: ([\d.]+)';
            end

            % Extract the IP address using a regular expression
            tokens = regexp(cmdout, expr, 'tokens');
            localIP = tokens{1}{1};

            % Display the local IP address
            disp(['Local IP Address: ', localIP]);
            app.LocalIPTextArea.Value = char(localIP);

            % Replace 'hostname' with the actual hostname of the target PC
            if exist('app.hostname.mat','file')
                loadedData = load("app.hostname.mat");
                disp(loadedData)
                app.hostname = loadedData.hostname;
            else
                app.hostname = '';
            end
            % Use the 'ping' command to resolve the hostname to an IP address
            [status, cmdout] = system(['ping -n 1 ', app.hostname]);

            % Extract the IP address using a regular expression
            expr = '\[([\d.]+)\]';
            tokens = regexp(cmdout, expr, 'tokens');

            if ~isempty(tokens)
                ipAddress = tokens{1}{1};
                % Display the IP address
                disp(['IP Address of ', app.hostname, ': ', ipAddress]);
                app.ServerIPTextArea.Value = ipAddress;
            else
                disp(['Could not resolve IP address for ', app.hostname]);
                errordlg('The server could not be reached. Use the manual mode!');
            end


            initializeConnections(app)


            %             % Setup RFID serial connections
            %             app.arduino = serialport(arduinoport, 9600);
            %             flush(app.arduino);
            %
            %
            %             % Setup scale serial connections
            %             %             scalePort = 'COM13';  % Change as necessary
            %             app.scaleSerial = serialport(scale, 9600);
            %             flush(app.scaleSerial);

            %skip unnecessary lines
            app.skip_lines;

            disp('Listening for RFID data...');
            app.RFIDTextArea.Value = 'Waiting for RFID...';

            %             try
            while app.Running
                if app.push_calibrate == 1
                    break
                end
                if app.pushed_start == 1
                    app.Running = false;
                    % Delete the timer

                    % Stop the timer
                    %stop(app.timerCallback);
                end

                app.checkAllTextAreas;
                app.checkWeight;

                try
                    % Wait for RFID data from Arduino
                    bytesAvailable = app.arduino.NumBytesAvailable;
                    if bytesAvailable > 0
                        data = read(app.arduino, bytesAvailable, 'char');
                        disp(['Raw Data: ', data]);  % Display raw data for debugging
                        if ~isempty(data)
                            rfid_data = strtrim(data);
                            fprintf('Received RFID ID: %s\n', rfid_data);

                            rfidData = rfid_data(3:14);  % crop whitespaces and arrow
                            app.RFIDTextArea.Value = num2str(rfidData);

                            % Stop the timer
                            %                                 stop(app.Timer);

                            % Delete the timer
                            delete(app.Timer);
                        end
                    end
                catch ME
                    % Handle errors
                    warning(['Error: ', ME.message]);
                end
                pause(0.1);  % Adjust pause duration for responsiveness
                %                     try
                while app.Running
                    drawnow
                    data = readline(app.scaleSerial);
                    if ~isempty(data)
                        % Extract weight from the data using specified regex pattern
                        weightMatch = regexp(data, '\d+,-?\d+\.\d+,[^,]+,\d+\.\d+,', 'match', 'once');
                        if ~isempty(weightMatch)
                            weightParts = strsplit(weightMatch, ',');
                            weight = str2double(weightParts{2})*1000;
                            flag_10_reads = 0;
                            if flag_10_reads < 10
                                if ~isempty(weight)

                                    weightData = [weightData, weight];

                                    % Ensure weightData contains only the last 10 values
                                    if length(weightData) > 10
                                        weightData(1) = [];  % Remove the oldest entry
                                    end

                                    flag_10_reads = flag_10_reads + 1;
                                    if length(weightData) == 10
                                        % Calculate mean weight
                                        meanWeight = mean(weightData);
                                        disp(['Calculated mean weight: ', num2str(meanWeight)]);

                                        % Update the GUI data with mean weight

                                        app.checkAllTextAreas;

                                        weightData = [];  % Reset weight data
                                        flag_10_reads = 0;
                                        break;  % Exit the loop after calculating mean for 10 readings
                                    end
                                    app.CurrentWeightgTextArea.Value = num2str(weight);
                                    break
                                end
                            end
                        end
                    end
                    %                             catch ME
                    %                                 % Handle errors
                    %                                 warning(['Error: ', ME.message]);
                    %                             end
                    %                     if app.pushed_start == 1
                    %                         app.Running = false;
                    %                         % Delete the timer
                    %
                    %                         % Stop the timer
                    %                         stop(app.timerCallback);
                    %                     end
                end
                %                     catch ME
                %                         % Handle errors
                %                         warning(['Error: ', ME.message]);
                %                     end
            end
            %             catch ME
            %                 % Handle errors
            %                 warning(['Error: ', ME.message]);
            %             end
        end

        % Button pushed function: WeighButton
        function WeighButtonPushed(app, event)
            global meanWeight;

            if isempty(meanWeight)
                warning('meanWeight is not defined or has not been calculated yet.');
            else
                app.MeasuredWeightgTextArea.Value = num2str(meanWeight);
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.pushed_start = 1;
            pause(1);

            global BpodSystem % Import the global BpodSystem object

            protocolName = char(app.ProtocolTextArea.Value);
            subjectName = char(app.SubjectTextArea.Value);
            settingsName = char(app.SettingsTextArea.Value);
            dateInfo = datestr(now, 30);
            fileName = [subjectName '_' protocolName '_' dateInfo '.mat'];
            BpodSystem.Path.CurrentDataFile = fullfile(BpodSystem.Path.DataFolder, subjectName, protocolName, 'Session Data', fileName);
            settingsFileName = fullfile(BpodSystem.Path.DataFolder, subjectName, protocolName, 'Session Settings', [settingsName '.mat']);
            dataFolder = fullfile(BpodSystem.Path.DataFolder,subjectName,protocolName,'Session Data');
            if ~exist(dataFolder)
                mkdir(dataFolder);
            end

            % On Bpod r2+, if FlexIO channels are configured as analog,
            % setup binary data file
            if BpodSystem.MachineType > 3
                nAnalogChannels = sum(BpodSystem.HW.FlexIO_ChannelTypes == 2);
                if nAnalogChannels > 0
                    analogFilename = [BpodSystem.Path.CurrentDataFile(1:end-4) '_ANLG.dat'];
                    if BpodSystem.Status.RecordAnalog == 1
                        BpodSystem.AnalogDataFile = fopen(analogFilename,'w');
                        if BpodSystem.AnalogDataFile == -1
                            error(['Error: Could not open the analog data file: ' analogFilename])
                        end
                    end
                    BpodSystem.Status.nAnalogSamples = 0;
                end
            end

            BpodSystem.Status.Live = 1;
            BpodSystem.Status.LastEvent = 0;
            BpodSystem.GUIData.ProtocolName = protocolName;
            BpodSystem.GUIData.SubjectName = subjectName;
            BpodSystem.GUIData.SettingsFileName = settingsFileName;
            BpodSystem.Path.Settings = settingsFileName;
            settingStruct = load(BpodSystem.Path.Settings);
            F = fieldnames(settingStruct);
            fieldName = F{1};
            BpodSystem.ProtocolSettings = eval(['settingStruct.' fieldName]);
            BpodSystem.Data = struct;
            if BpodSystem.MachineType > 3
                if nAnalogChannels > 0
                    BpodSystem.Data.Analog = struct;
                    BpodSystem.Data.Analog.info = struct;
                    BpodSystem.Data.Analog.FileName = analogFilename;
                    BpodSystem.Data.Analog.nChannels = nAnalogChannels;
                    BpodSystem.Data.Analog.channelNumbers = find(BpodSystem.HW.FlexIO_ChannelTypes == 2);
                    BpodSystem.Data.Analog.SamplingRate = BpodSystem.HW.FlexIO_SamplingRate;
                    BpodSystem.Data.Analog.nSamples = 0;
                    % Add human-readable info about data fields to 'info struct
                    BpodSystem.Data.Analog.info.FileName = 'Complete path and filename of the binary file to which the raw data was logged';
                    BpodSystem.Data.Analog.info.nChannels = 'The number of Flex I/O channels configured as analog input';
                    BpodSystem.Data.Analog.info.channelNumbers = 'The indexes of Flex I/O channels configured as analog input';
                    BpodSystem.Data.Analog.info.SamplingRate = 'The sampling rate of the analog data. Units = Hz';
                    BpodSystem.Data.Analog.info.nSamples = 'The total number of analog samples captured during the behavior session';
                    BpodSystem.Data.Analog.info.Samples = 'Analog measurements captured. Rows are separate analog input channels. Units = Volts';
                    BpodSystem.Data.Analog.info.Timestamps = 'Time of each sample (computed from sample index and sampling rate)';
                    BpodSystem.Data.Analog.info.TrialNumber = 'Experimental trial during which each analog sample was captured';
                    BpodSystem.Data.Analog.info.TrialData = 'A cell array of Samples. Each cell contains samples captured during a single trial.';
                end
            end
            protocolFolderPath = fullfile(BpodSystem.Path.ProtocolFolder,protocolName);
            protocolPath = fullfile(BpodSystem.Path.ProtocolFolder,protocolName,[protocolName '.m']);
            addpath(protocolFolderPath);
            set(BpodSystem.GUIHandles.RunButton, 'cdata', BpodSystem.GUIData.PauseButton, 'TooltipString', 'Press to pause session');

            % % Send metadata to Bpod Phone Home program (disabled pending a more stable server)
            % isOnline = BpodSystem.check4Internet();
            % if (isOnline == 1) && (BpodSystem.SystemSettings.PhoneHome == 1)
            %     BpodSystem.BpodPhoneHome(1);
            % end

            if BpodSystem.Status.AnalogViewer
                set(BpodSystem.GUIHandles.RecordButton, 'Enable', 'off')
            end

            BpodSystem.Status.BeingUsed = 1;
            BpodSystem.Status.SessionStartFlag = 1;
            BpodSystem.ProtocolStartTime = now*100000;
            BpodSystem.resetSessionClock();
            %close(BpodSystem.GUIHandles.rfidGUI.UIFigure);
            disp(' ');
            disp(['Starting ' protocolName]);
            set(BpodSystem.GUIHandles.CurrentStateDisplay, 'String', '---');
            set(BpodSystem.GUIHandles.PreviousStateDisplay, 'String', '---');
            set(BpodSystem.GUIHandles.LastEventDisplay, 'String', '---');
            set(BpodSystem.GUIHandles.TimeDisplay, 'String', '0:00:00');
            if sum(BpodSystem.InputsEnabled(BpodSystem.HW.Inputs == 'P')) == 0
                warning(['All Bpod behavior ports are currently disabled.'...
                    'If your protocol requires behavior ports, enable them from the settings menu.'])
            end

            % Call the default close request function
            app.AutopilotUIFigureCloseRequest;
            run(protocolPath);
        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
            %             app.pushed_start = 1;
            %             pause(1);
            app.AutopilotUIFigureCloseRequest;

            LaunchManager;
        end

        % Value changed function: SettingsTextArea
        function SettingsTextAreaValueChanged(app, event)
            checkAllTextAreas(app);
        end

        % Value changed function: ProtocolTextArea
        function ProtocolTextAreaValueChanged(app, event)
            checkAllTextAreas(app);
        end

        % Value changed function: SubjectTextArea
        function SubjectTextAreaValueChanged(app, event)
            checkAllTextAreas(app);
        end

        % Close request function: AutopilotUIFigure
        function AutopilotUIFigureCloseRequest(app, event)
            % Set the flag to false to stop the while loop

            if isfield(app, 'Timer') && ~isempty(app.Timer) && isvalid(app.Timer)
                stop(app.Timer);
                delete(app.Timer);
                disp('Timer stopped and deleted.');
            end

            app.Running = false;
            app.pushed_start = 1;

            % Save data for UDP and USB
            hostname = app.hostname;
            save('app.hostname.mat', 'hostname')
            arduino = app.arduino; %#ok<ADPROPLC>
            scaleSerial = app.scaleSerial; %#ok<ADPROPLC>
            save('USBports.mat','arduino','scaleSerial')
            disp('USB Ports saved')

            % Close serial connections
            if ~isempty(app.scaleSerial)
                delete(app.scaleSerial);
                disp('Serial port closed and deleted.');
            else
                disp('No valid scale serial port object to close.');
            end
            if ~isempty(app.arduino)
                delete(app.arduino);
                disp('Serial port closed and deleted.');
            else
                disp('No valid scale serial port object to close.');
            end


            % Wait briefly to ensure the loop has time to exit
            pause(0.2);

            delete(app);
        end

        % Value changed function: MeasuredWeightgTextArea
        function MeasuredWeightgTextAreaValueChanged(app, event)
            checkAllTextAreas(app);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            if ~strcmp(app.RFIDTextArea.Value, '-')
                app.SubjectTextArea.Value = "Waiting for Server...";
                app.ProtocolTextArea.Value = "Waiting for Server...";
                app.SettingsTextArea.Value = "Waiting for Server...";
                
                [paradigm, setting , subjectID, stage,photometry, optogenetic,widefield,e_phys,change_stage] = sendUDP(app, string(app.RFIDTextArea.Value), string(app.MeasuredWeightgTextArea.Value), char(app.ServerIPTextArea.Value), str2double(app.ServerPortTextArea.Value) ,char(app.LocalIPTextArea.Value), str2double(app.LocalPortTextArea.Value));
                app.SubjectTextArea.Value = subjectID;
                app.ProtocolTextArea.Value = paradigm;
                app.SettingsTextArea.Value = setting;
                app.PhotometryTextArea.Value = photometry;
                app.OptogenTextArea.Value = optogenetic;
                app.EPhysTextArea.Value = e_phys;
                app.WidefieldimagingCheckBox.Value = false;
                disp(stage)
                disp(change_stage)
                %             if change_stage == 1
                %                 %             TODO: add a flag to udp code
                %                 % Define the prompt for the input dialog
                %                 prompt = {'The animal reached a new training stage'};
                %
                %                 % Define the title for the dialog box
                %                 dlgtitle = 'New Stage';
                %
                %                 % Define the options for the buttons
                %                 btn1 = 'Continue';
                %                 btn2 = 'Cancel (use old settings)';
                %
                %                 % Display the question dialog box and capture the user's response
                %                 choice = questdlg(prompt, dlgtitle, btn1, btn2, btn1);
                %
                %                 % Check the user's response
                %                 switch choice
                %                     case 'Continue'
                %                         disp('User selected Continue.');
                %                         app.ProtocolTextArea.Value = paradigm;
                %                         app.SettingsTextArea.Value = setting;
                %                     case 'Cancel (use old settings)'
                %                         disp('User selected Cancel.');
                %                         % TODO: send old paradigm if flag ~isempty
                %                         app.ProtocolTextArea.Value = old_paradigm;
                %                         app.SettingsTextArea.Value = old_setting;
                %                         return;
                %                     otherwise
                %                         app.ProtocolTextArea.Value = old_paradigm;
                %                         app.SettingsTextArea.Value = old_setting;
                %                         disp('User closed the dialog.');
                %                         return;
                %                 end
                %             end
            else
                app.ProtocolTextArea.Value = "Waiting for Server...";
                app.SettingsTextArea.Value = "Waiting for Server...";

                [paradigm, setting , stage,photometry, optogenetic,widefield,e_phys,change_stage]= sendUDP1(app, string(app.SubjectTextArea.Value), string(app.MeasuredWeightgTextArea.Value), char(app.ServerIPTextArea.Value), str2double(app.ServerPortTextArea.Value) ,char(app.LocalIPTextArea.Value), str2double(app.LocalPortTextArea.Value));
                app.ProtocolTextArea.Value = paradigm;
                app.SettingsTextArea.Value = setting;
                app.PhotometryTextArea.Value = photometry;
                app.OptogenTextArea.Value = optogenetic;
                app.EPhysTextArea.Value = e_phys;
                app.WidefieldimagingCheckBox.Value = false;
                disp(stage)
                disp(change_stage)

                %                 if change_stage == 1
                %                     %             TODO: add a flag to udp code
                %                     % Define the prompt for the input dialog
                %                     prompt = {'The animal reached a new training stage'};
                %
                %                     % Define the title for the dialog box
                %                     dlgtitle = 'New Stage';
                %
                %                     % Define the options for the buttons
                %                     btn1 = 'Continue';
                %                     btn2 = 'Cancel (use old settings)';
                %
                %                     % Display the question dialog box and capture the user's response
                %                     choice = questdlg(prompt, dlgtitle, btn1, btn2, btn1);
                %
                %                     % Check the user's response
                %                     switch choice
                %                         case 'Continue'
                %                             disp('User selected Continue.');
                %                             app.ProtocolTextArea.Value = paradigm;
                %                             app.SettingsTextArea.Value = setting;
                %                         case 'Cancel (use old settings)'
                %                             disp('User selected Cancel.');
                %                             % TODO: send old paradigm if flag ~isempty
                %                             app.ProtocolTextArea.Value = old_paradigm;
                %                             app.SettingsTextArea.Value = old_setting;
                %                             return;
                %                         otherwise
                %                             app.ProtocolTextArea.Value = old_paradigm;
                %                             app.SettingsTextArea.Value = old_setting;
                %                             disp('User closed the dialog.');
                %                             return;
                %                     end
                %                 end
            end


            %% probably not necessary as we just display it in the GUI

            %             %             TODO: add a flag to udp code
            %                         if ~isempty(opto_flag)
            %
            %                             % Define the prompt for the input dialog
            %                             prompt = {sprintf('Please insert glassfibers at Position(s) %s', position)};
            %
            %                             % Define the title for the dialog box
            %                             dlgtitle = 'Glassfiber Location';
            %
            %                             % Define the options for the buttons
            %                             btn1 = 'Continue';
            %                             btn2 = 'Cancel';
            %
            %                             % Display the question dialog box and capture the user's response
            %                             choice = questdlg(prompt, dlgtitle, btn1, btn2, btn1);
            %
            %                             % Check the user's response
            %                             switch choice
            %                                 case 'Continue'
            %                                     disp('User selected Continue.');
            %                                 case 'Cancel (use old settings)'
            %                                     disp('User selected Cancel.');
            %                                     % change reaction if continue wasn't
            %                                     % pushed?
            %                                     return;
            %                                 otherwise
            %                                     disp('User closed the dialog.');
            %                                     return;
            %                             end
            %                         end
        end

        % Button pushed function: TareButton
        function TareTareButtonPushed(app, event)
            app.CurrentWeightgTextArea.Value = 'Taring in progress...';

            app.push_calibrate = 1;
            pause(1)

            % Open settings
            cmd = 'x';
            fprintf(app.scaleSerial, cmd);
            app.wait_for_settings;
            app.skip_lines;

            % Send tare command
            tare = '1';
            fprintf(app.scaleSerial, tare);
            app.wait_for_settings;
            app.skip_lines;

            % close settings
            close = 'x';
            fprintf(app.scaleSerial, close);
            app.skip_lines;
            app.skip_lines;


            app.push_calibrate = 0;
        end

        % Value changed function: enableSubject
        function enableSubjectValueChanged(app, event)
            value = app.enableSubject.Value;

            if value == 49
                app.Lamp.Visible = "on";
                app.SubjectTextArea.Editable = 'on';
                app.RFIDTextArea.Value = '-';
            else
                app.Lamp.Visible = "off";
                app.SubjectTextArea.Editable = 'off';
                app.SubjectTextArea.Value = '';
                app.RFIDTextArea.Value = 'Waiting for RFID...';
            end
            drawnow
        end

        % Button pushed function: CalibrationButton
        function CalibrationButtonPushed(app, event)
            app.CurrentWeightgTextArea.Value = 'Calibration in progress...';

            % Define the prompt for the input dialog
            prompt = {'Remove everything from the platform!'};

            % Define the title for the dialog box
            dlgtitle = 'Calibration';

            % Define the options for the buttons
            btn1 = 'OK';
            btn2 = 'Cancel';

            % Display the question dialog box and capture the user's response
            choice = questdlg(prompt, dlgtitle, btn1, btn2, btn1);

            % Check the user's response
            switch choice
                case 'OK'
                    disp('User selected OK.');
                    app.push_calibrate = 1;
                    pause(1)

                    %skip unnecessary lines
                    %                     app.skip_lines;

                    % Open settings
                    cmd = 'x';
                    fprintf(app.scaleSerial, cmd);
                    app.wait_for_settings;

                    % Wait for the response
                    app.skip_lines;


                    % Send tare command
                    tare = '1';
                    fprintf(app.scaleSerial, tare);
                    app.wait_for_settings;

                    % Wait for the response
                    app.skip_lines;

                case 'Cancel'
                    disp('User selected Cancel.');
                    return;
                otherwise
                    disp('User closed the dialog.');
                    return;
            end

            % Define the prompt for the input dialog
            prompt = {'Place a defined weight on top of the platform!'};

            % Define the title for the dialog box
            dlgtitle = 'Calibration';

            % Define the dimensions for the input field
            dims = [1 35]; % 1 row and 35 columns width

            % Define default input values (optional)
            definput = {'Weight (g)'};

            % Display the input dialog box and capture the user's input
            answer = inputdlg(prompt, dlgtitle, dims, definput);

            % User clicked OK, extract and display the input
            weight = answer{1};
            weight = strrep(weight, ',', '.');
            % Original floating-point number
            x = (str2double(weight))/1000;

            while isnan(x) || x <= 0
                inputerror = errordlg('Input is not valid. Enter a positive number!', 'Input Error');
                uiwait(inputerror)
                answer = inputdlg(prompt, dlgtitle, dims, definput);
                % User clicked OK, extract and display the input
                if ~isempty(answer)
                    weight = answer{1};
                    weight = strrep(weight, ',', '.');
                    % Original floating-point number
                    x = (str2double(weight))/1000;
                else
                    answer = [];
                    break
                end
            end

            % Check if the user clicked OK or Cancel
            if ~isempty(answer)

                % User clicked OK, extract and display the input
                weight = answer{1};
                weight = strrep(weight, ',', '.');
                % Original floating-point number
                x = (str2double(weight))/1000;

                % Convert the number to a string
                x_str = num2str(x, '%.12f'); % Use a specific format to ensure adequate precision

                % Define the total length of the final string, including zeros
                total_length = 12;

                % Get the current length of the string representation
                current_length = length(x_str);

                % Calculate the number of zeros to add before and after the number
                num_zeros = total_length - current_length;

                % Create a string with the zeros added before and after the original number
                % For example, if we want to add zeros after the number to achieve the length
                if num_zeros > 0
                    % Adding zeros after the number (for this example)
                    x_padded_str = [x_str, repmat('0', 1, num_zeros)];
                else
                    % If the number of zeros needed is negative, this will handle cases where
                    % the original number already exceeds the total length
                    x_padded_str = x_str;
                end

                % Convert the padded string back to a float if needed
                %                 x_final = str2double(x_padded_str);

                % Display the final result
                disp(x_padded_str); % Displays the formatted number as a string
                %                 disp(x_final);     % Displays the number as a float

                cal = '2';
                fprintf(app.scaleSerial, cal);
                app.wait_for_config;

                % Wait for the response
                app.skip_lines;


                fprintf(app.scaleSerial, x_padded_str);
                app.wait_for_settings

                app.skip_lines;


                % close settings
                close = 'x';
                fprintf(app.scaleSerial, close);
                % Wait for the response
                app.skip_lines;
                app.skip_lines;


            else
                % User clicked Cancel
                disp('User clicked Cancel.');
                % close settings
                close = 'x';
                fprintf(app.scaleSerial, close);
                % Wait for the response
                app.skip_lines;
                app.skip_lines;
            end

            app.push_calibrate = 0;
            pause(1)
        end

        % Button pushed function: ChangeServerButton
        function ChangeServerButtonPushed(app, event)
            % Define the prompt for the input dialog
            prompt = {'Enter the Name of the Server(e.g. CMPxxxx)'};

            % Define the title for the dialog box
            dlgtitle = 'Change Server';

            % Define the dimensions for the input field
            dims = [1 35]; % 1 row and 35 columns width

            % Define default input values (optional)
            definput = {'Server Name'};

            % Display the input dialog box and capture the user's input
            answer = inputdlg(prompt, dlgtitle, dims, definput);


            % Check if the user clicked OK or Cancel
            if ~isempty(answer)
                % User clicked OK, extract and display the input
                app.hostname = answer{1};

                % Use the 'ping' command to resolve the hostname to an IP address
                [status, cmdout] = system(['ping -n 1 ', app.hostname]);

                % Extract the IP address using a regular expression
                expr = '\[([\d.]+)\]';
                tokens = regexp(cmdout, expr, 'tokens');

                if ~isempty(tokens)
                    ipAddress = tokens{1}{1};
                    % Display the IP address
                    disp(['IP Address of ', app.hostname, ': ', ipAddress]);
                    app.ServerIPTextArea.Value = ipAddress;
                else
                    disp(['Could not resolve IP address for ', app.hostname]);
                    errordlg('The server could not be reached. Enter a valid name!');
                end
            else
                % User clicked Cancel
                disp('User clicked Cancel.');
            end
        end

        % Button pushed function: OSKButton
        function OSKButtonPushed(app, event)
            system('start osk');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AutopilotUIFigure = uifigure('Visible', 'off');
            app.AutopilotUIFigure.Name = 'Autopilot';
            app.AutopilotUIFigure.CloseRequestFcn = createCallbackFcn(app, @AutopilotUIFigureCloseRequest, true);
            app.SubjectTextAreaLabel = uilabel(app.AutopilotUIFigure);
            app.SubjectTextAreaLabel.Text = 'Subject';
            app.SubjectTextArea = uitextarea(app.AutopilotUIFigure);
            app.SubjectTextArea.ValueChangedFcn = createCallbackFcn(app, @SubjectTextAreaValueChanged, true);
            app.SubjectTextArea.Editable = 'off';
            app.STARTButton = uibutton(app.AutopilotUIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Text = 'START';
            app.ProtocolTextAreaLabel = uilabel(app.AutopilotUIFigure);
            app.ProtocolTextAreaLabel.Text = 'Protocol';
            app.ProtocolTextArea = uitextarea(app.AutopilotUIFigure);
            app.ProtocolTextArea.ValueChangedFcn = createCallbackFcn(app, @ProtocolTextAreaValueChanged, true);
            app.ProtocolTextArea.Editable = 'off';
            app.ManualButton = uibutton(app.AutopilotUIFigure, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Text = 'Manual';
            app.SettingsTextAreaLabel = uilabel(app.AutopilotUIFigure);
            app.SettingsTextAreaLabel.Text = 'Settings';
            app.SettingsTextArea = uitextarea(app.AutopilotUIFigure);
            app.SettingsTextArea.ValueChangedFcn = createCallbackFcn(app, @SettingsTextAreaValueChanged, true);
            app.SettingsTextArea.Editable = 'off';
            app.WeighButton = uibutton(app.AutopilotUIFigure, 'push');
            app.WeighButton.ButtonPushedFcn = createCallbackFcn(app, @WeighButtonPushed, true);
            app.WeighButton.Text = 'Weigh';
            app.MeasuredWeightgTextAreaLabel = uilabel(app.AutopilotUIFigure);
            app.MeasuredWeightgTextAreaLabel.Text = 'Measured Weight (g)';
            app.MeasuredWeightgTextArea = uitextarea(app.AutopilotUIFigure);
            app.MeasuredWeightgTextArea.ValueChangedFcn = createCallbackFcn(app, @MeasuredWeightgTextAreaValueChanged, true);
            app.MeasuredWeightgTextArea.Editable = 'off';
            app.LocalIPLabel = uilabel(app.AutopilotUIFigure);
            app.LocalIPLabel.Text = 'Local IP:';
            app.LocalIPTextArea = uitextarea(app.AutopilotUIFigure);
            app.LocalIPTextArea.Editable = 'off';
            app.LocalPortTextAreaLabel = uilabel(app.AutopilotUIFigure);
            app.LocalPortTextAreaLabel.Text = 'Local Port:';
            app.LocalPortTextArea = uitextarea(app.AutopilotUIFigure);
            app.LocalPortTextArea.Editable = 'off';
            app.LocalPortTextArea.Value = {'5001'};
            app.ServerIPTextAreaLabel = uilabel(app.AutopilotUIFigure);
            app.ServerIPTextAreaLabel.Text = 'Server IP:';
            app.ServerIPTextArea = uitextarea(app.AutopilotUIFigure);
            app.ServerIPTextArea.Editable = 'off';
            app.ServerPortTextAreaLabel = uilabel(app.AutopilotUIFigure);
            app.ServerPortTextAreaLabel.Text = 'Server Port:';
            app.ServerPortTextArea = uitextarea(app.AutopilotUIFigure);
            app.ServerPortTextArea.Editable = 'off';
            app.ServerPortTextArea.Value = {'5002'};
            app.RFIDTextAreaLabel = uilabel(app.AutopilotUIFigure);
            app.RFIDTextAreaLabel.Text = 'RFID';
            app.RFIDTextArea = uitextarea(app.AutopilotUIFigure);
            app.RFIDTextArea.Editable = 'off';
            app.LoadDataButton = uibutton(app.AutopilotUIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Enable = 'off';
            app.LoadDataButton.Text = 'Load Data';
            app.CurrentWeightgLabel = uilabel(app.AutopilotUIFigure);
            app.CurrentWeightgLabel.Text = 'Current Weight (g)';
            app.CurrentWeightgTextArea = uitextarea(app.AutopilotUIFigure);
            app.CurrentWeightgTextArea.Editable = 'off';
            app.TareButton = uibutton(app.AutopilotUIFigure, 'push');
            app.TareButton.ButtonPushedFcn = createCallbackFcn(app, @TareTareButtonPushed, true);
            app.TareButton.Icon = fullfile(pathToMLAPP, '100958064-tare-icon-isolated-on-white-background-vector-illustration.jpg');
            app.TareButton.Text = '';
            app.TareScaleLabel = uilabel(app.AutopilotUIFigure);
            app.TareScaleLabel.Text = 'Tare Scale';
            app.enableSubject = uiswitch(app.AutopilotUIFigure, 'slider');
            app.enableSubject.Items = {'0', '1'};
            app.enableSubject.ValueChangedFcn = createCallbackFcn(app, @enableSubjectValueChanged, true);
            app.enableSubject.Value = '0';
            app.Lamp = uilamp(app.AutopilotUIFigure);
            app.Lamp.Visible = 'off';
            app.CalibrationButton = uibutton(app.AutopilotUIFigure, 'push');
            app.CalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationButtonPushed, true);
            app.CalibrationButton.Icon = fullfile(pathToMLAPP, 'calibrate.jpg');
            app.CalibrationButton.Text = '';
            app.CalibrateScaleLabel = uilabel(app.AutopilotUIFigure);
            app.CalibrateScaleLabel.Text = 'Calibrate Scale';
            app.ChangeServerButton = uibutton(app.AutopilotUIFigure, 'push');
            app.ChangeServerButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeServerButtonPushed, true);
            app.ChangeServerButton.Text = 'Change Server';
            app.OSKButton = uibutton(app.AutopilotUIFigure, 'push');
            app.OSKButton.ButtonPushedFcn = createCallbackFcn(app, @OSKButtonPushed, true);
            app.OSKButton.Icon = fullfile(pathToMLAPP, 'Icon.png');
            app.OSKButton.Text = '';
            app.EPhysTextAreaLabel = uilabel(app.AutopilotUIFigure);
            app.EPhysTextAreaLabel.Text = 'E-Phys';
            app.EPhysTextArea = uitextarea(app.AutopilotUIFigure);
            app.EPhysTextArea.Editable = 'off';
            app.PhotometryTextArea = uitextarea(app.AutopilotUIFigure);
            app.PhotometryTextArea.Editable = 'off';
            app.PhotometryTextArea_2Label = uilabel(app.AutopilotUIFigure);
            app.PhotometryTextArea_2Label.Text = 'Photometry';
            app.OptogenTextArea = uitextarea(app.AutopilotUIFigure);
            app.OptogenTextArea.Editable = 'off';
            app.OptogenTextAreaLabel = uilabel(app.AutopilotUIFigure);
            app.OptogenTextAreaLabel.Text = 'Optogen.';
            app.WidefieldimagingCheckBox = uicheckbox(app.AutopilotUIFigure);
            app.WidefieldimagingCheckBox.Text = 'Widefield imaging';
            app.WidefieldimagingCheckBox.Value = true;
            % Show the figure after all components are created
            app.AutopilotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AutoPilot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AutopilotUIFigure)

            % Execute the startup function
            runStartupFcn(app, @timerCallback)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AutopilotUIFigure)
        end
    end
end